classdef AppDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RaceChallange2022UIFigure      matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        ConnecttoMainCarMenu           matlab.ui.container.Menu
        SaveDataMenu                   matlab.ui.container.Menu
        SaveDataAsMenu                 matlab.ui.container.Menu
        ResetMenu                      matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        VehicleCurrentstateTab         matlab.ui.container.Tab
        MobileConnectButton            matlab.ui.control.Button
        CarConnectButton               matlab.ui.control.Button
        MobileConnectionLamp           matlab.ui.control.Lamp
        MobileConnectionLampLabel      matlab.ui.control.Label
        SendSpeedChangeMessagetotheDriverPanel  matlab.ui.container.Panel
        MaintainaspeedofSpinner        matlab.ui.control.Spinner
        MaintainaspeedofSpinnerLabel   matlab.ui.control.Label
        MHLabel                        matlab.ui.control.Label
        SendButton                     matlab.ui.control.Button
        SpeedGaugeLabel_2              matlab.ui.control.Label
        BatterySoc                     matlab.ui.control.NinetyDegreeGauge
        RoutedetailsPanel              matlab.ui.container.Panel
        OptimisedSpeedEditField        matlab.ui.control.NumericEditField
        OptimisedSpeedEditFieldLabel   matlab.ui.control.Label
        InsertNewStopButton            matlab.ui.control.Button
        NextStopDropDown               matlab.ui.control.DropDown
        NextStopDropDownLabel          matlab.ui.control.Label
        RemainingdistancetofinishlineKmhEditField  matlab.ui.control.NumericEditField
        RemainingdistancetofinishlineKmhEditFieldLabel  matlab.ui.control.Label
        RemainingDIstancetilNextstopKmhEditField  matlab.ui.control.NumericEditField
        RemainingDIstancetilNextstopKmhEditFieldLabel  matlab.ui.control.Label
        RoadInclineEditField_2         matlab.ui.control.NumericEditField
        RoadInclineEditField_2Label    matlab.ui.control.Label
        MotorsPowerEditField           matlab.ui.control.NumericEditField
        MotorsPowerEditFieldLabel      matlab.ui.control.Label
        AirPressureEditField           matlab.ui.control.NumericEditField
        AirPressureEditFieldLabel      matlab.ui.control.Label
        MapPanel                       matlab.ui.container.Panel
        SolarPenalPowerEditField       matlab.ui.control.NumericEditField
        SolarPenalPowerEditFieldLabel  matlab.ui.control.Label
        RoadInclineEditField           matlab.ui.control.NumericEditField
        RoadInclineEditFieldLabel      matlab.ui.control.Label
        SolarIrradianceEditField       matlab.ui.control.NumericEditField
        SolarIrradianceEditFieldLabel  matlab.ui.control.Label
        MainCarConnectionLamp          matlab.ui.control.Lamp
        MainCarConnectionLampLabel     matlab.ui.control.Label
        ResetDistanceButton            matlab.ui.control.Button
        DIstanceTravelledEditField     matlab.ui.control.NumericEditField
        DIstanceTravelledEditFieldLabel  matlab.ui.control.Label
        SpeedGauge                     matlab.ui.control.NinetyDegreeGauge
        SpeedGaugeLabel                matlab.ui.control.Label
        AnalysisTab                    matlab.ui.container.Tab
        Panel_2                        matlab.ui.container.Panel
        InputPowerSlider               matlab.ui.control.Slider
        InputPowerSliderLabel          matlab.ui.control.Label
        OutputPowerSlider              matlab.ui.control.Slider
        OutputPowerSliderLabel         matlab.ui.control.Label
        CoefficientsLabel              matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        SelecttocomparepridictedandcalculatevaluesLabel  matlab.ui.control.Label
        ConsumptionLabel               matlab.ui.control.Label
        MotorsEnergyLabel              matlab.ui.control.Label
        TemperatureCheckBox            matlab.ui.control.CheckBox
        ProducedSolarEnergyCheckBox    matlab.ui.control.CheckBox
        SolarIrradianceCheckBox        matlab.ui.control.CheckBox
        MotorEnergyConsumption         matlab.ui.control.CheckBox
        VehicleSpeedCheckBox           matlab.ui.control.CheckBox
        BatterySOCCheckBox             matlab.ui.control.CheckBox
        AnalysisGraph                  matlab.ui.control.UIAxes
        GraphsTab                      matlab.ui.container.Tab
        AirPressure                    matlab.ui.control.UIAxes
        TemperatureGraph               matlab.ui.control.UIAxes
        InclineGraph                   matlab.ui.control.UIAxes
        IrradianceGraph                matlab.ui.control.UIAxes
        Tab                            matlab.ui.container.Tab
        ConnecttomobileButton          matlab.ui.control.Button
        LastUpdateChaseCarEditField    matlab.ui.control.EditField
        LastUpdateChaseCarEditFieldLabel  matlab.ui.control.Label
        UpdatePosButton                matlab.ui.control.Button
        ChaseCarPosusingIpPanel        matlab.ui.container.Panel
        ChaseCarPosusingMobilePanel    matlab.ui.container.Panel
    end

    
    properties (Access = private)
        
        %% Variables to store Race data 
        Temperature % Description
        Irradiance % Description
        Airpressure % Description
        Incline % Description
        WVDensity % Description
        Latitude  
        Longitude
        Time % Description
        DistanceTravelled % Description


        %Register to check which box is selected in the analysis tab
        CheckBoxSelected

        %% Variables required to display race route
        RaceRoute % Description
        TempRoute % Description
        TempRoute1 % Description
        MobileDevice % Description

        %% Measured graph Lines Objects
        IrradianceGraphLine % Description
        MotorsTempGraphLine % Description
        InclineGraphLine % Description
        SOCGraphLine % Description
        MotorsEnergyLine % Description  
        ProducedSolarEnergyLine % Description
        TemperatureGraphLine
        AirPressureGraphLine

        AnalysisIrradianceLineCalc
        AnalysisIrradianceLineEst

        %% Predicted Graph Lines Objects
        PredIrradianceGraphLine % Description
        PredMotorsTempGraphLine % Description
        PredInclineGraphLine % Description
        PredSOCGraphLine % Description
        PredMotorsEnergyLine % Description  
        PredProducedSolarEnergyLine % Description 
        

        %% Variables used to save data into a file 
        FilePath       % Description
        FileIndex      % Description
        AppTitle = 'Australian Race Challange 2022' % Description

        %% Flags  
        MainCarConnected ;      % Flag to keep count of Main car connection
        Is_Saved                % Flag to know if the data is already saved or not
        MobileConnected;        % Flag to keep count of mobile connection  
    end
    
    methods (Access = private)
        
        %% Function used to make sure that only one box is selected at a time on the Analysis graph
        function SelectCheckBox(app, Number)
            switch Number
                case 1
                    app.BatterySOCCheckBox.Value = 1;
                    app.VehicleSpeedCheckBox.Value = 0;
                    app.MotorEnergyConsumption.Value = 0;
                    app.SolarIrradianceCheckBox.Value = 0;
                    app.ProducedSolarEnergyCheckBox.Value = 0;
                    app.TemperatureCheckBox.Value = 0;
                case 2
                    app.BatterySOCCheckBox.Value = 0;
                    app.VehicleSpeedCheckBox.Value = 1;
                    app.MotorEnergyConsumption.Value = 0;
                    app.SolarIrradianceCheckBox.Value = 0;
                    app.ProducedSolarEnergyCheckBox.Value = 0;
                    app.TemperatureCheckBox.Value = 0;
                case 3
                    app.BatterySOCCheckBox.Value = 0;
                    app.VehicleSpeedCheckBox.Value = 0;
                    app.MotorEnergyConsumption.Value = 1;
                    app.SolarIrradianceCheckBox.Value = 0;
                    app.ProducedSolarEnergyCheckBox.Value = 0;
                    app.TemperatureCheckBox.Value = 0;
                case 4
                    app.BatterySOCCheckBox.Value = 0;
                    app.VehicleSpeedCheckBox.Value = 0;
                    app.MotorEnergyConsumption.Value = 0;
                    app.SolarIrradianceCheckBox.Value = 1;
                    app.ProducedSolarEnergyCheckBox.Value = 0;
                    app.TemperatureCheckBox.Value = 0;
                case 5
                    app.BatterySOCCheckBox.Value = 0;
                    app.VehicleSpeedCheckBox.Value = 0;
                    app.MotorEnergyConsumption.Value = 0;
                    app.SolarIrradianceCheckBox.Value = 0;
                    app.ProducedSolarEnergyCheckBox.Value = 1;
                    app.TemperatureCheckBox.Value = 0;
                case 6
                    app.BatterySOCCheckBox.Value = 0;
                    app.VehicleSpeedCheckBox.Value = 0;
                    app.MotorEnergyConsumption.Value = 0;
                    app.SolarIrradianceCheckBox.Value = 0;
                    app.ProducedSolarEnergyCheckBox.Value = 0;
                    app.TemperatureCheckBox.Value = 1;
            end
        end
        
        %% Function to update the analysis graphproperties
        function UpdateAnalysisGraph(app,Reg)
            switch(Reg)
                case 1
                    app.PredictedLine = animatedline('Color','r','Parent',app.AnalysisGraph);
                    app.CalculatedLine = animatedline('Color','g','Parent',app.AnalysisGraph);
                case 2
                    app.PredictedLine = animatedline('Color','r','Parent',app.AnalysisGraph);
                    app.CalculatedLine = animatedline('Color','g','Parent',app.AnalysisGraph);
                case 3
                    app.PredictedLine = animatedline('Color','r','Parent',app.AnalysisGraph);
                    app.CalculatedLine = animatedline('Color','g','Parent',app.AnalysisGraph);
                case 4
                    app.PredictedLine = animatedline('Color','r','Parent',app.AnalysisGraph);
                    app.CalculatedLine = animatedline('Color','g','Parent',app.AnalysisGraph);
                case 5
                    app.PredictedLine = animatedline('Color','r','Parent',app.AnalysisGraph);
                    app.CalculatedLine = animatedline('Color','g','Parent',app.AnalysisGraph);
                case 6
                    app.PredictedLine = animatedline('Color','r','Parent',app.AnalysisGraph);
                    app.CalculatedLine = animatedline('Color','g','Parent',app.AnalysisGraph);
            end
        end
        
        %% Function used to confirm and safely close the application 
        function ConfirmCloseApp(app,~,event)
            if (event.SelectedOption == "No")
                if(app.MobileConnected == 1)
                    % Disconnect activated mobile connection
                    app.MobileDevice = nan;
                end
                delete(app.RaceChallange2022UIFigure);
            elseif(event.SelectedOption == "Yes")
                app.SaveDataMenu();
                if(app.MobileConnected == 1)
                    % Disconnect activated mobile connection
                    app.MobileDevice = nan;
                end
                delete(app.RaceChallange2022UIFigure);
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % clear Variables from the base Workspace in order to disconnect
            % any on going mobile connection 
            evalin('base','clear all');

            % Set default variable values
            app.Is_Saved = false;
            app.MainCarConnected = false;
            app.MobileConnected = false;


            %set up the chase car route 
            Pos = whereami();
            app.TempRoute= geoplayer(Pos.lat,Pos.lon,15,'Parent',app.ChaseCarPosusingIpPanel);
            app.TempRoute1= geoplayer(Pos.lat,Pos.lon,15,'Parent',app.ChaseCarPosusingMobilePanel);
            
            % Set Default checkBox 
            app.CheckBoxSelected = 1;
            app.SelectCheckBox(app.CheckBoxSelected);
            
            %Configure Graph Lines 
            %initiate all graphs 
            app.InclineGraphLine = animatedline('Parent',app.InclineGraph);
            app.IrradianceGraphLine = animatedline('Parent',app.IrradianceGraph);
            app.TemperatureGraphLine = animatedline('Parent',app.TemperatureGraph);
            app.AirPressureGraphLine = animatedline('Parent',app.AirPressure);

            app.AnalysisIrradianceLineCalc = animatedline('Parent',app.AnalysisGraph,'Color','r','DisplayName','Measured');
            app.AnalysisIrradianceLineEst = animatedline('Parent',app.AnalysisGraph,'Color','b','DisplayName','Estimated');
            legend(app.AnalysisGraph);
        end

        % Menu selected function: ConnecttoMainCarMenu
        function ConnecttoMainCarMenuSelected(app, event)
            
            % turn the lamp to green and MainCarConnected Register to 1
            app.MainCarConnectionLamp.Color = 'green';
            app.MainCarConnected = 1;

            %load data from mat file
            Data = load('DataFile.mat');
            app.Airpressure = Data.Airpressure;
            app.Incline = Data.Incline;
            app.Irradiance = Data.Irradiance;
            app.Temperature = Data.Temperature;
            app.WVDensity = Data.WVDensity;
            app.Latitude = Data.Latitude;
            app.Longitude = Data.Longitude;
            app.DistanceTravelled = Data.DistanceTravelled;
            
            
            %plot the route 
            app.RaceRoute = geoplayer(app.Latitude(1),app.Longitude(1),12,'Parent',app.MapPanel);
            plotRoute(app.RaceRoute,app.Latitude,app.Longitude);


            % Set deafult configuration for the route
            %VehiclePosition = geoplayer(app.Latitude(1),app.Longitude(1),12,'Parent',app.MapPanel);
            
            %plotRoute(VehiclePosition,app.Latitude,app.Longitude);

            %Temporary variables 
            Speed = 40 ;
            BatterySOC = 90;
            app.OptimisedSpeedEditField.Value = 29 ;
            
            i = 1;
            while(1)

               %display Main Car on the route
               if(app.MainCarConnected == 1)

                    % display values on the Edit fields
                    app.RoadInclineEditField.Value= app.Incline(i);
                    app.SolarIrradianceEditField.Value = app.Irradiance(i);
                    app.DIstanceTravelledEditField.Value = app.DistanceTravelled(i);
                    
                    app.RemainingDIstancetilNextstopKmhEditField.Value = 317 - app.DistanceTravelled(i);
                    app.RemainingdistancetofinishlineKmhEditField.Value = 3030 - app.DistanceTravelled(i);

                    %display Speed and SOC
                    app.SpeedGauge.Value = Speed;
                    app.BatterySoc.Value = BatterySOC;


                    %update data on graphs
                    addpoints(app.InclineGraphLine,i,app.Incline(i));
                    addpoints(app.IrradianceGraphLine,i,app.Irradiance(i));
                    addpoints(app.TemperatureGraphLine,i,app.Temperature(i));
                    addpoints(app.AirPressureGraphLine,i,app.DistanceTravelled(i));

                    addpoints(app.AnalysisIrradianceLineCalc,i,app.Irradiance(i));
                    addpoints(app.AnalysisIrradianceLineEst,i,app.Irradiance(i)-0.05);
                    drawnow;
                    
                    %update Analysis graph values
                    %app.UpdateAnalysisGraph();
                    %Update position of the Main Car on the route

                    plotPosition(app.RaceRoute,app.Latitude(i),app.Longitude(i),'Label','Main Car');
                    
                    if(app.MobileConnected == 1)
                        %Update position of the chase Car on the route
                        plotPosition(app.TempRoute1,app.MobileDevice.Latitude,app.MobileDevice.Longitude, ...
                            'Label','Chase Car');
                    end


                    %Calculate Time
                    time=Data.NextCoordDistance(i)/Speed;
                    
                    pause(time);

                    i = i+1;
               end
            end
          
        end

        % Button pushed function: UpdatePosButton
        function UpdatePosButtonPushed(app, event)
            Pos = whereami();
            plotPosition(app.TempRoute,Pos.lat,Pos.lon,'Label','Chase Car');
        end

        % Value changed function: BatterySOCCheckBox
        function BatterySOCCheckBoxValueChanged(app, event)
            app.CheckBoxSelected = 1;
            app.SelectCheckBox(1);
        end

        % Value changed function: VehicleSpeedCheckBox
        function VehicleSpeedCheckBoxValueChanged(app, event)
            app.CheckBoxSelected = 2;
            app.SelectCheckBox(2);
        end

        % Value changed function: MotorEnergyConsumption
        function MotorEnergyConsumptionValueChanged(app, event)
            app.CheckBoxSelected = 3;
            app.SelectCheckBox(3);
            
        end

        % Value changed function: SolarIrradianceCheckBox
        function SolarIrradianceCheckBoxValueChanged(app, event)
            app.CheckBoxSelected = 4;
            app.SelectCheckBox(4);
        end

        % Value changed function: ProducedSolarEnergyCheckBox
        function ProducedSolarEnergyCheckBoxValueChanged(app, event)
            app.CheckBoxSelected = 5;
            app.SelectCheckBox(5);
        end

        % Value changed function: TemperatureCheckBox
        function TemperatureCheckBoxValueChanged(app, event)
            app.CheckBoxSelected = 6;
            app.SelectCheckBox(6);
        end

        % Close request function: RaceChallange2022UIFigure
        function RaceChallange2022UIFigureCloseRequest(app, event)
        uiconfirm(app.RaceChallange2022UIFigure,['Would you like to save the data before closing the application? (Note: Unsaved Data will ' ...
                'be deleted once application is closed)'], 'Confirm Close','Options',{'Yes','No'}, 'CloseFcn',@app.ConfirmCloseApp);
        end

        % Button pushed function: ConnecttomobileButton
        function ConnecttomobileButtonPushed(app, event)
            app.ConnecttomobileButton.Enable = false;

            if(app.ConnecttomobileButton.Text =="Connect to mobile")
            
                % Disconnect previous connection
                clear mobiledev
                clearvars -global
                clearvars -except app

                clear app.MobileDevice;
            
                if(~isempty(mobiledevlist))

                    app.MobileDevice = mobiledev ;
                    %check for available list
            
            
                    if(app.MobileDevice.Connected)
                        app.MobileDevice.PositionSensorEnabled = 1;
                        app.MobileDevice.Logging = 1 ;
                        app.MobileConnected = 1;
                        app.ConnecttomobileButton.Text = 'Disconnect';
                    end
                end
            else% Disconnect previous connection
                clearvars -except app
                app.MobileDevice = nan;
                
                app.MobileConnected = 0;
                app.ConnecttomobileButton.Text = 'Connect to mobile';
            end
            pause(1);
            app.ConnecttomobileButton.Enable = true;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
        uiconfirm(app.RaceChallange2022UIFigure,['Would you like to save data before closing the application? (Note: Unsaved Data will ' ...
                'be deleted once application is closed)'], 'Confirm Close','Options',{'Yes','No'}, 'CloseFcn',@app.ConfirmCloseApp);
        end

        % Menu selected function: SaveDataMenu
        function SaveDataMenuSelected(app, event)
            if(app.Is_Saved)
                % create a table which will contains all of the race data variables
                RaceDataTable = table(app.Airpressure(:),app.Irradiance(:),app.Temperature(:), ...
                                 'VariableNames',{'Air pressure','Irradiance','Temperature'});

                %add some properties to the table
                RaceDataTable.Properties.Description = 'Race Data';
                %DataTable.Properties.RowNames = app.Time ;
                
                % Save data into the the specific file choosed by the user
                switch(app.FileIndex)
                    case 1
                        %write data into the xlsx file
                        writetable(RaceDataTable,app.FilePath,'Sheet',1,'Range','A1','WriteMode','replacefile');
                    case 2
                        %write data into the text file
                        writetable(RaceDataTable,app.FilePath,'Delimiter',' ','WriteMode','overwrite'); 
                    case 3
                        %save into a .mat file
                        save(app.FilePath,'RaceDataTable','-mat','-append');
                end
            else
                app.SaveDataAsMenuSelected();
            end
            
        end

        % Menu selected function: SaveDataAsMenu
        function SaveDataAsMenuSelected(app, event)
            % Create a filter so the user can choose the file type
            filter = {'*.xlsx';'*.txt';'*.mat'};
            
            % Open the save tab so the user can select the location 
            [file, path, app.FileIndex] = uiputfile(filter,'Select a file');
            
            % check if the user pressed OK or Cancel
            if isequal(file,0) || isequal(path,0)
                % User pressed Cancel
            else
                % User selected the path and pressed ok
                
                % Merge user selected path and Name together 
                app.FilePath = fullfile(path,file);

                % create a table which will contains all of the race data variables
                RaceDataTable = table(app.Airpressure(:),app.Irradiance(:),app.Temperature(:), ...
                                 'VariableNames',{'Air pressure','Irradiance','Temperature'});

                %add some properties to the table
                RaceDataTable.Properties.Description = 'Race Data';
                %DataTable.Properties.RowNames = app.Time ;
                
                % Save data into the the specific file choosed by the user
                switch(app.FileIndex)
                    case 1
                        %write data into the xlsx file
                        writetable(RaceDataTable,app.FilePath,'Sheet',1,'Range','A1');
                    case 2
                        %write data into the text file
                        writetable(RaceDataTable,app.FilePath,'Delimiter',' '); 
                    case 3
                        %save into a .mat file
                        save(app.FilePath,'RaceDataTable','-mat');
                end
                
                %once the file is saved turn on the Saved Data flag
                app.Is_Saved = true;
                
                
                %change the name of the main toolbar
                app.RaceChallange2022UIFigure.Name = fullfile(app.AppTitle,' Data Saved as (',app.FilePath,')');

                
            end
        end

        % Button pushed function: InsertNewStopButton
        function InsertNewStopButtonPushed(app, event)
            prompt = {'Enter Stop name:','Enter Stop Distance:'};
            Title = 'Insert Stop on the route ';
            dims = [1 35];
            definput = {'hsv','200'};
            NewStop = inputdlg(prompt,Title,dims,definput);
            
            %adding the new stop to the list
            if ~any(ismember(app.NextStopDropDown.Items, NewStop))
                app.NextStopDropDown.Items = [app.NextStopDropDown.Items NewStop{1}];
                app.NextStopDropDown.ItemsData = [app.NextStopDropDown.Items NewStop{2}];
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RaceChallange2022UIFigure = uifigure('Visible', 'off');
            app.RaceChallange2022UIFigure.Name = 'Race Challange 2022';
            app.RaceChallange2022UIFigure.CloseRequestFcn = createCallbackFcn(app, @RaceChallange2022UIFigureCloseRequest, true);
            app.Menu = uimenu(app.RaceChallange2022UIFigure);
            app.Menu.Text = 'Menu';
            app.ConnecttoMainCarMenu = uimenu(app.Menu);
            app.ConnecttoMainCarMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnecttoMainCarMenuSelected, true);
            app.ConnecttoMainCarMenu.Text = 'Connect to Main Car';
            app.SaveDataMenu = uimenu(app.Menu);
            app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuSelected, true);
            app.SaveDataMenu.Separator = 'on';
            app.SaveDataMenu.Accelerator = 's';
            app.SaveDataMenu.Text = 'Save Data  ';
            app.SaveDataAsMenu = uimenu(app.Menu);
            app.SaveDataAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataAsMenuSelected, true);
            app.SaveDataAsMenu.Separator = 'on';
            app.SaveDataAsMenu.Text = 'Save Data As';
            app.ResetMenu = uimenu(app.Menu);
            app.ResetMenu.Separator = 'on';
            app.ResetMenu.Accelerator = 'R';
            app.ResetMenu.Text = 'Reset ';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.RaceChallange2022UIFigure);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.RaceChallange2022UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.VehicleCurrentstateTab = uitab(app.TabGroup);
            app.VehicleCurrentstateTab.Title = 'Vehicle Current state';
            app.SpeedGaugeLabel = uilabel(app.VehicleCurrentstateTab);
            app.SpeedGaugeLabel.Text = 'Vehicle Speed (Mph)';
            app.SpeedGauge = uigauge(app.VehicleCurrentstateTab, 'ninetydegree');
            app.DIstanceTravelledEditFieldLabel = uilabel(app.VehicleCurrentstateTab);
            app.DIstanceTravelledEditFieldLabel.Text = 'DIstance Travelled';
            app.DIstanceTravelledEditField = uieditfield(app.VehicleCurrentstateTab, 'numeric');
            app.ResetDistanceButton = uibutton(app.VehicleCurrentstateTab, 'push');
            app.ResetDistanceButton.Text = 'Reset Distance';
            app.MainCarConnectionLampLabel = uilabel(app.VehicleCurrentstateTab);
            app.MainCarConnectionLampLabel.Text = 'Main Car Connection';
            app.MainCarConnectionLamp = uilamp(app.VehicleCurrentstateTab);
            app.SolarIrradianceEditFieldLabel = uilabel(app.VehicleCurrentstateTab);
            app.SolarIrradianceEditFieldLabel.Text = 'Solar Irradiance';
            app.SolarIrradianceEditField = uieditfield(app.VehicleCurrentstateTab, 'numeric');
            app.RoadInclineEditFieldLabel = uilabel(app.VehicleCurrentstateTab);
            app.RoadInclineEditFieldLabel.Text = 'Road Incline';
            app.RoadInclineEditField = uieditfield(app.VehicleCurrentstateTab, 'numeric');
            app.SolarPenalPowerEditFieldLabel = uilabel(app.VehicleCurrentstateTab);
            app.SolarPenalPowerEditFieldLabel.Text = 'Solar Penal Power';
            app.SolarPenalPowerEditField = uieditfield(app.VehicleCurrentstateTab, 'numeric');
            app.MapPanel = uipanel(app.VehicleCurrentstateTab);
            app.MapPanel.Title = 'Map';
            app.AirPressureEditFieldLabel = uilabel(app.VehicleCurrentstateTab);
            app.AirPressureEditFieldLabel.Text = 'Air Pressure';
            app.AirPressureEditField = uieditfield(app.VehicleCurrentstateTab, 'numeric');
            app.MotorsPowerEditFieldLabel = uilabel(app.VehicleCurrentstateTab);
            app.MotorsPowerEditFieldLabel.Text = 'Motors Power ';
            app.MotorsPowerEditField = uieditfield(app.VehicleCurrentstateTab, 'numeric');
            app.RoadInclineEditField_2Label = uilabel(app.VehicleCurrentstateTab);
            app.RoadInclineEditField_2Label.Text = 'Road Incline';
            app.RoadInclineEditField_2 = uieditfield(app.VehicleCurrentstateTab, 'numeric');
            app.RoutedetailsPanel = uipanel(app.VehicleCurrentstateTab);
            app.RoutedetailsPanel.Title = 'Route details';
            app.RemainingDIstancetilNextstopKmhEditFieldLabel = uilabel(app.RoutedetailsPanel);
            app.RemainingDIstancetilNextstopKmhEditFieldLabel.Text = 'Remaining DIstance til Next stop (Km/h)';
            app.RemainingDIstancetilNextstopKmhEditField = uieditfield(app.RoutedetailsPanel, 'numeric');
            app.RemainingdistancetofinishlineKmhEditFieldLabel = uilabel(app.RoutedetailsPanel);
            app.RemainingdistancetofinishlineKmhEditFieldLabel.Text = 'Remaining distance to finish line (Km/h)';
            app.RemainingdistancetofinishlineKmhEditField = uieditfield(app.RoutedetailsPanel, 'numeric');
            app.NextStopDropDownLabel = uilabel(app.RoutedetailsPanel);
            app.NextStopDropDownLabel.Text = 'Next Stop';
            app.NextStopDropDown = uidropdown(app.RoutedetailsPanel);
            app.NextStopDropDown.Items = {};
            app.NextStopDropDown.Value = {};
            app.InsertNewStopButton = uibutton(app.RoutedetailsPanel, 'push');
            app.InsertNewStopButton.ButtonPushedFcn = createCallbackFcn(app, @InsertNewStopButtonPushed, true);
            app.InsertNewStopButton.Text = 'Insert New Stop';
            app.OptimisedSpeedEditFieldLabel = uilabel(app.RoutedetailsPanel);
            app.OptimisedSpeedEditFieldLabel.Text = 'Optimised Speed';
            app.OptimisedSpeedEditField = uieditfield(app.RoutedetailsPanel, 'numeric');
            app.BatterySoc = uigauge(app.VehicleCurrentstateTab, 'ninetydegree');
            app.SpeedGaugeLabel_2 = uilabel(app.VehicleCurrentstateTab);
            app.SpeedGaugeLabel_2.Text = 'Battery SOC';
            app.SendSpeedChangeMessagetotheDriverPanel = uipanel(app.VehicleCurrentstateTab);
            app.SendSpeedChangeMessagetotheDriverPanel.Title = 'Send Speed Change Message to the Driver';
            app.SendButton = uibutton(app.SendSpeedChangeMessagetotheDriverPanel, 'push');
            app.SendButton.Text = 'Send';
            app.MHLabel = uilabel(app.SendSpeedChangeMessagetotheDriverPanel);
            app.MHLabel.Text = 'M/H';
            app.MaintainaspeedofSpinnerLabel = uilabel(app.SendSpeedChangeMessagetotheDriverPanel);
            app.MaintainaspeedofSpinnerLabel.Text = 'Maintain a speed of';
            app.MaintainaspeedofSpinner = uispinner(app.SendSpeedChangeMessagetotheDriverPanel);
            app.MobileConnectionLampLabel = uilabel(app.VehicleCurrentstateTab);
            app.MobileConnectionLampLabel.Text = 'Mobile Connection';
            app.MobileConnectionLamp = uilamp(app.VehicleCurrentstateTab);
            app.CarConnectButton = uibutton(app.VehicleCurrentstateTab, 'push');
            app.CarConnectButton.Text = 'Connect';
            app.MobileConnectButton = uibutton(app.VehicleCurrentstateTab, 'push');
            app.MobileConnectButton.Text = 'Connect';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis ';
            app.AnalysisGraph = uiaxes(app.AnalysisTab);
            xlabel(app.AnalysisGraph, 'X')
            ylabel(app.AnalysisGraph, 'Y')
            zlabel(app.AnalysisGraph, 'Z')
            app.Panel = uipanel(app.AnalysisTab);
            app.BatterySOCCheckBox = uicheckbox(app.Panel);
            app.BatterySOCCheckBox.ValueChangedFcn = createCallbackFcn(app, @BatterySOCCheckBoxValueChanged, true);
            app.BatterySOCCheckBox.Text = ' Battery SOC';
            app.VehicleSpeedCheckBox = uicheckbox(app.Panel);
            app.VehicleSpeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @VehicleSpeedCheckBoxValueChanged, true);
            app.VehicleSpeedCheckBox.Text = ' Vehicle Speed';
            app.MotorEnergyConsumption = uicheckbox(app.Panel);
            app.MotorEnergyConsumption.ValueChangedFcn = createCallbackFcn(app, @MotorEnergyConsumptionValueChanged, true);
            app.MotorEnergyConsumption.Text = '';
            app.SolarIrradianceCheckBox = uicheckbox(app.Panel);
            app.SolarIrradianceCheckBox.ValueChangedFcn = createCallbackFcn(app, @SolarIrradianceCheckBoxValueChanged, true);
            app.SolarIrradianceCheckBox.Text = 'Solar Irradiance';
            app.ProducedSolarEnergyCheckBox = uicheckbox(app.Panel);
            app.ProducedSolarEnergyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProducedSolarEnergyCheckBoxValueChanged, true);
            app.ProducedSolarEnergyCheckBox.Text = 'Produced Solar Energy ';
            app.TemperatureCheckBox = uicheckbox(app.Panel);
            app.TemperatureCheckBox.ValueChangedFcn = createCallbackFcn(app, @TemperatureCheckBoxValueChanged, true);
            app.TemperatureCheckBox.Text = 'Temperature';
            app.MotorsEnergyLabel = uilabel(app.Panel);
            app.MotorsEnergyLabel.Text = 'Motors Energy';
            app.ConsumptionLabel = uilabel(app.Panel);
            app.ConsumptionLabel.Text = 'Consumption';
            app.SelecttocomparepridictedandcalculatevaluesLabel = uilabel(app.Panel);
            app.SelecttocomparepridictedandcalculatevaluesLabel.Text = 'Select to compare pridicted and calculate values';
            app.Panel_2 = uipanel(app.AnalysisTab);
            app.CoefficientsLabel = uilabel(app.Panel_2);
            app.CoefficientsLabel.Text = 'Coefficients';
            app.OutputPowerSliderLabel = uilabel(app.Panel_2);
            app.OutputPowerSliderLabel.Text = 'Output Power';
            app.OutputPowerSlider = uislider(app.Panel_2);
            app.OutputPowerSlider.Limits = [0 1];
            app.InputPowerSliderLabel = uilabel(app.Panel_2);
            app.InputPowerSliderLabel.Text = 'Input Power';
            app.InputPowerSlider = uislider(app.Panel_2);
            app.InputPowerSlider.Limits = [0 1];
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.IrradianceGraph = uiaxes(app.GraphsTab);
            title(app.IrradianceGraph, 'Solar Irradiance')
            xlabel(app.IrradianceGraph, 'Time (s)')
            ylabel(app.IrradianceGraph, 'Irradiance')
            zlabel(app.IrradianceGraph, 'Z')
            app.InclineGraph = uiaxes(app.GraphsTab);
            title(app.InclineGraph, 'Road Incline')
            xlabel(app.InclineGraph, 'Time (s)')
            ylabel(app.InclineGraph, 'Incline')
            zlabel(app.InclineGraph, 'Z')
            app.TemperatureGraph = uiaxes(app.GraphsTab);
            title(app.TemperatureGraph, 'Temperature')
            xlabel(app.TemperatureGraph, 'Time (s)')
            ylabel(app.TemperatureGraph, 'Temperature in Kelvin')
            zlabel(app.TemperatureGraph, 'Z')
            app.AirPressure = uiaxes(app.GraphsTab);
            title(app.AirPressure, 'Distance Travelled')
            xlabel(app.AirPressure, 'Time (s)')
            ylabel(app.AirPressure, 'Distance Travelled in KM/H')
            zlabel(app.AirPressure, 'Z')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.ChaseCarPosusingMobilePanel = uipanel(app.Tab);
            app.ChaseCarPosusingMobilePanel.Title = 'Chase Car (Pos using Mobile)';
            app.ChaseCarPosusingIpPanel = uipanel(app.Tab);
            app.ChaseCarPosusingIpPanel.Title = 'Chase Car (Pos using Ip)';
            app.UpdatePosButton = uibutton(app.Tab, 'push');
            app.UpdatePosButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePosButtonPushed, true);
            app.UpdatePosButton.Text = 'Update Pos';
            app.LastUpdateChaseCarEditFieldLabel = uilabel(app.Tab);
            app.LastUpdateChaseCarEditFieldLabel.Text = 'Last Update(Chase Car)';
            app.LastUpdateChaseCarEditField = uieditfield(app.Tab, 'text');
            app.LastUpdateChaseCarEditField.Editable = 'off';
            app.ConnecttomobileButton = uibutton(app.Tab, 'push');
            app.ConnecttomobileButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttomobileButtonPushed, true);
            app.ConnecttomobileButton.Text = 'Connect to mobile';
            % Show the figure after all components are created
            app.RaceChallange2022UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RaceChallange2022UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RaceChallange2022UIFigure)
        end
    end
end