classdef CloseButtonDialogueBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Panel      matlab.ui.container.Panel
        NoButton   matlab.ui.control.Button
        YesButton  matlab.ui.control.Button
        DoYouWanttosavetheCollectedDatabeforeClosingtheApplicationLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApplication)
            app.MainApp = MainApplication;
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.MainApp.OptionSelectedIndialougeBox('Yes');
            delete(app)
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.MainApp.OptionSelectedIndialougeBox('No');
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.OptionSelectedIndialougeBox('Exit');
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.DoYouWanttosavetheCollectedDatabeforeClosingtheApplicationLabel = uilabel(app.Panel);
            app.DoYouWanttosavetheCollectedDatabeforeClosingtheApplicationLabel.Text = 'Do You Want to save Collected Data before Closing the Application (Note: the data will be deleted if not saved)';
            app.YesButton = uibutton(app.Panel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.Panel, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CloseButtonDialogueBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end